classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        RunSimulationButton             matlab.ui.control.Button
        ParametersLabel                 matlab.ui.control.Label
        LoadParametersButton            matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        PositionTab                     matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        OrientationTab                  matlab.ui.container.Tab
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        LinearVelocityTab               matlab.ui.container.Tab
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        AngularVelocityTab              matlab.ui.container.Tab
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_12                       matlab.ui.control.UIAxes
        AllActuatorsTab                 matlab.ui.container.Tab
        UIAxes13                        matlab.ui.control.UIAxes
        TrajectoryTab                   matlab.ui.container.Tab
        UIAxes14                        matlab.ui.control.UIAxes
        ErrorWP0WP1Tab                  matlab.ui.container.Tab
        StandardDeviationDistanceWP0WP1EditField  matlab.ui.control.NumericEditField
        StandardDeviationDistanceWP0WP1EditFieldLabel  matlab.ui.control.Label
        AverageDistanceWP0WP1EditField  matlab.ui.control.NumericEditField
        AverageDistanceWP0WP1EditFieldLabel  matlab.ui.control.Label
        UIAxes15                        matlab.ui.control.UIAxes
        ErrorWP1WP2Tab                  matlab.ui.container.Tab
        StandardDeviationDistanceWP1WP2EditField  matlab.ui.control.NumericEditField
        StandardDeviationDistanceWP1WP2EditFieldLabel  matlab.ui.control.Label
        AverageDistanceWP1WP2EditField  matlab.ui.control.NumericEditField
        AverageDistanceWP1WP2EditFieldLabel  matlab.ui.control.Label
        UIAxes16                        matlab.ui.control.UIAxes
        ErrorWP2WP3Tab                  matlab.ui.container.Tab
        StandardDeviationDistanceWP2WP3EditField  matlab.ui.control.NumericEditField
        StandardDeviationDistanceWP2WP3EditFieldLabel  matlab.ui.control.Label
        AverageDistanceWP2WP3EditField  matlab.ui.control.NumericEditField
        AverageDistanceWP2WP3EditFieldLabel  matlab.ui.control.Label
        UIAxes17                        matlab.ui.control.UIAxes
        ErrorWP3WP4Tab                  matlab.ui.container.Tab
        StandardDeviationDistanceWP3WP4EditField  matlab.ui.control.NumericEditField
        StandardDeviationDistanceWP3WP4EditFieldLabel  matlab.ui.control.Label
        AverageDistanceWP3WP4EditField  matlab.ui.control.NumericEditField
        AverageDistanceWP3WP4EditFieldLabel  matlab.ui.control.Label
        UIAxes18                        matlab.ui.control.UIAxes
        ErrorWP4WP5Tab                  matlab.ui.container.Tab
        StandardDeviationDistanceWP4WP5EditField  matlab.ui.control.NumericEditField
        StandardDeviationDistanceWP4WP5EditFieldLabel  matlab.ui.control.Label
        AverageDistanceWP4WP5EditField  matlab.ui.control.NumericEditField
        AverageDistanceWP4WP5EditFieldLabel  matlab.ui.control.Label
        UIAxes19                        matlab.ui.control.UIAxes
        ErrorWP5WP6Tab                  matlab.ui.container.Tab
        StandardDeviationDistanceWP5WP6EditField  matlab.ui.control.NumericEditField
        StandardDeviationDistanceWP5WP6EditFieldLabel  matlab.ui.control.Label
        AverageDistanceWP5WP6EditField  matlab.ui.control.NumericEditField
        AverageDistanceWP5WP6EditFieldLabel  matlab.ui.control.Label
        UIAxes20                        matlab.ui.control.UIAxes
        ErrorWP6WP7Tab                  matlab.ui.container.Tab
        StandardDeviationDistanceWP6WP7EditField  matlab.ui.control.NumericEditField
        StandardDeviationDistanceWP6WP7EditFieldLabel  matlab.ui.control.Label
        AverageDistanceWP6WP7EditField  matlab.ui.control.NumericEditField
        AverageDistanceWP6WP7EditFieldLabel  matlab.ui.control.Label
        UIAxes21                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
           
            table = readtable("data.xlsx");
            app.UITable.Data = table;
            app.UITable.ColumnName = table.Properties.VariableNames;

        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

            mdl = 'RoboticFish_NGC';
            open_system(mdl);

            % Save table in data
            data = app.UITable.Data;

            % Set parameters
            for i = 1:size(data, 2)
                paramName=app.UITable.ColumnName(i);
                paramValue = data{1, i}; 
                if(i<2)
                    set_param('RoboticFish_NGC', string(paramName), string(paramValue))
                elseif(i<7)
                    set_param('RoboticFish_NGC/Target Position', string(paramName), string(paramValue));
                else
                    set_param('RoboticFish_NGC/Robotic-Fish Model & PropulsionSystem', string(paramName), string(paramValue));
                end
            end

            % Save model
            save_system(mdl);

            % Run simulation
            simOut=sim(mdl);

            % Squeeze variables
            simOut.X=squeeze(simOut.X);
            simOut.Y=squeeze(simOut.Y);
            simOut.Z=squeeze(simOut.Z);
            simOut.Roll=squeeze(simOut.Roll);
            simOut.Pitch=squeeze(simOut.Pitch);
            simOut.Yaw=squeeze(simOut.Yaw);
            simOut.u=squeeze(simOut.u);
            simOut.v=squeeze(simOut.v);
            simOut.w=squeeze(simOut.w);
            simOut.p=squeeze(simOut.p);
            simOut.q=squeeze(simOut.q);
            simOut.r=squeeze(simOut.r);

            % Save time
            time= simOut.tout;
            dt = simOut.SimulationMetadata.ModelInfo.SolverInfo.FixedStepSize;
            duration = simOut.SimulationMetadata.ModelInfo.StopTime;

            % Plot
            data1=[simOut.X, simOut.Xr];
            plot(app.UIAxes,time,data1);
            legend(app.UIAxes,'X','Xr');

            data2=[simOut.Y, simOut.Yr];
            plot(app.UIAxes_2,time,data2);
            legend(app.UIAxes_2,'Y','Yr');

            data3=[simOut.Z, simOut.Zr];
            plot(app.UIAxes_3,time,data3);
            legend(app.UIAxes_3,'Z','Zr');

            plot(app.UIAxes_4,time,simOut.Roll);

            plot(app.UIAxes_5,time,simOut.Pitch);

            plot(app.UIAxes_6,time,simOut.Yaw);

            plot(app.UIAxes_7,time,simOut.u);

            plot(app.UIAxes_8,time,simOut.v);

            plot(app.UIAxes_9,time,simOut.w);

            plot(app.UIAxes_10,time,simOut.p);

            plot(app.UIAxes_11,time,simOut.q);

            plot(app.UIAxes_12,time,simOut.r);

            data4=[simOut.f_HT3, simOut.Thetastr_HT3, simOut.uHT1, simOut.uHT2, simOut.uVT1, simOut.uVT2, simOut.uVT3];
            plot(app.UIAxes13 ,time, data4);
            legend(app.UIAxes13,'f_HT3 [Hz]','Thetastr_HT3 [rad]','uHT1','uHT2','uVT1','uVT2','uVT3')

            % Plot trajectory
            data5=[simOut.Xr, simOut.X];
            data6=[simOut.Yr, simOut.Y];
            plot(app.UIAxes14,data5,data6);
            WP_x = [0 10 15 20 20 10 10 0];
            WP_y = [0 0 5 0 -10 -10 0 0];
            hold(app.UIAxes14);
            plot(app.UIAxes14,WP_x,WP_y,'go');
            legend(app.UIAxes14,'Reference Trajectory','Effective Trajectory','Waypoints');
            str = {'WP0','WP1','WP2','WP3','WP4','WP5','WP6','WP7'};
            txt_x = [0.2 10.2 15.2 20.2 20.2 10.2 10.2 0.2];
            txt_y = [0.2 0.2 5.2 0.2 -10.2 -10.2 -0.2 -0.2];
            text(app.UIAxes14,txt_x,txt_y,str)

            % Error computation WP0-WP1
            WP0 = [0 0];
            WP1 = [9.7 0];
            timeWP0=1;
            timeWP1=floor(10.33/dt);
            Delta_t01 = timeWP1-timeWP0+1;
            XrWP0WP1 = 1:Delta_t01;
            YrWP0WP1 = 1:Delta_t01;
            distance_WP1WP2 = 1:Delta_t01;
            Lreal = 0;
            Dreal = 1:Delta_t01;
            Dref = 1:Delta_t01;
            DWP = WP1-WP0;
            Lref = sqrt(DWP(1).^2+DWP(2).^2);
            psi = atan(DWP(2)/DWP(1));

            for i=1:Delta_t01
                Dx = simOut.X(i+1)-simOut.X(i);
                Dy = simOut.Y(i+1)-simOut.Y(i);
                Dreal(i) = sqrt(Dx.^2+Dy.^2);
                Lreal = Lreal + Dreal(i);
            end

            Dref(1) = Dreal(1)*Lref/Lreal;
            XrWP0WP1(1) = WP0(1)+cos(psi)*Dref(1);
            YrWP0WP1(1) = WP0(2)+sin(psi)*Dref(1);
            distance_WP1WP2(1) = sqrt((simOut.X(timeWP0)-XrWP0WP1(1))^2+(simOut.Y(timeWP0)-YrWP0WP1(1))^2);
            for i=2:Delta_t01
                Dref(i) = Dreal(i)*Lref/Lreal;
                XrWP0WP1(i) = XrWP0WP1(i-1)+cos(psi)*Dref(i);
                YrWP0WP1(i) = YrWP0WP1(i-1)+sin(psi)*Dref(i);
                distance_WP1WP2(i) = sqrt((simOut.X(timeWP0+i)-XrWP0WP1(i))^2+(simOut.Y(timeWP0+i)-YrWP0WP1(i))^2);
            end

            app.AverageDistanceWP0WP1EditField.Value = mean(distance_WP1WP2);
            app.StandardDeviationDistanceWP0WP1EditField.Value = sqrt(sum(distance_WP1WP2.^2)/Delta_t01);

            % Plot WP0-WP1
            data01x=[XrWP0WP1.', simOut.X(timeWP0:timeWP1)];
            data01y=[YrWP0WP1.', simOut.Y(timeWP0:timeWP1)];
            plot(app.UIAxes15,data01x,data01y);
            hold(app.UIAxes15);
            WPx=[WP0(1) WP1(1)];
            WPy=[WP0(2) WP1(2)];
            plot(app.UIAxes15,WPx,WPy,'go');
            legend(app.UIAxes15,'Reference Trajectory','Effective Trajectory','Waypoints');
            str01 = {'WP0','WP1'};
            txt_x01 = [0 9.7];
            txt_y01 = [0 0];
            text(app.UIAxes15,txt_x01,txt_y01,str01)

            % Error computation WP1-WP2
            WP1 = [9.7 0];
            WP2 = [14.77 4.7];
            timeWP1=floor(10.33/dt);
            timeWP2=floor(20.08/dt);
            Delta_t12 = timeWP2-timeWP1+1;
            XrWP1WP2 = 1:Delta_t12;
            YrWP1WP2 = 1:Delta_t12;
            distance_WP1WP2 = 1:Delta_t12;
            Lreal = 0;
            Dreal = 1:Delta_t12;
            Dref = 1:Delta_t12;
            DWP = WP2-WP1;
            Lref = sqrt(DWP(1).^2+DWP(2).^2);
            psi = atan(DWP(2)/DWP(1));

            for i=1:Delta_t12
                Dx = simOut.X(i+1)-simOut.X(i);
                Dy = simOut.Y(i+1)-simOut.Y(i);
                Dreal(i) = sqrt(Dx.^2+Dy.^2);
                Lreal = Lreal + Dreal(i);
            end

            Dref(1) = Dreal(1)*Lref/Lreal;
            XrWP1WP2(1) = WP1(1)+cos(psi)*Dref(1);
            YrWP1WP2(1) = WP1(2)+sin(psi)*Dref(1);
            distance_WP1WP2(1) = sqrt((simOut.X(timeWP1)-XrWP1WP2(1))^2+(simOut.Y(timeWP1)-YrWP1WP2(1))^2);
            for i=2:Delta_t12
                Dref(i) = Dreal(i)*Lref/Lreal;
                XrWP1WP2(i) = XrWP1WP2(i-1)+cos(psi)*Dref(i);
                YrWP1WP2(i) = YrWP1WP2(i-1)+sin(psi)*Dref(i);
                distance_WP1WP2(i) = sqrt((simOut.X(timeWP1+i)-XrWP1WP2(i))^2+(simOut.Y(timeWP1+i)-YrWP1WP2(i))^2);
            end

            app.AverageDistanceWP1WP2EditField.Value = mean(distance_WP1WP2);                                  
            app.StandardDeviationDistanceWP1WP2EditField.Value = sqrt(sum(distance_WP1WP2.^2)/Delta_t12); 

            % Plot WP1-WP2
            data12x=[XrWP1WP2.', simOut.X(timeWP1:timeWP2)];
            data12y=[YrWP1WP2.', simOut.Y(timeWP1:timeWP2)];
            plot(app.UIAxes16,data12x,data12y);                                             
            hold(app.UIAxes16);
            WPx=[WP1(1) WP2(1)];
            WPy=[WP1(2) WP2(2)];
            plot(app.UIAxes16,WPx,WPy,'go');
            legend(app.UIAxes16,'Reference Trajectory','Effective Trajectory','Waypoints');
            str12 = {'WP1','WP2'};
            txt_x12 = [9.7 14.77];
            txt_y12 = [0 4.7];
            text(app.UIAxes16,txt_x12,txt_y12,str12)

            % Error computation WP2-WP3
            WP2 = [14.77 4.7];
            WP3 = [19.85 0.3];
            timeWP2=floor(20.08/dt);
            timeWP3=floor(34.20/dt);
            Delta_t23 = timeWP3-timeWP2+1;
            XrWP2WP3 = 1:Delta_t23;
            YrWP2WP3 = 1:Delta_t23;
            distance_WP2WP3 = 1:Delta_t23;
            Lreal = 0;
            Dreal = 1:Delta_t23;
            Dref = 1:Delta_t23;
            DWP = WP3-WP2;
            Lref = sqrt(DWP(1).^2+DWP(2).^2);
            psi = atan(DWP(2)/DWP(1));

            for i=1:Delta_t23
                Dx = simOut.X(i+1)-simOut.X(i);
                Dy = simOut.Y(i+1)-simOut.Y(i);
                Dreal(i) = sqrt(Dx.^2+Dy.^2);
                Lreal = Lreal + Dreal(i);
            end

            Dref(1) = Dreal(1)*Lref/Lreal;
            XrWP2WP3(1) = WP2(1)+cos(psi)*Dref(1);
            YrWP2WP3(1) = WP2(2)+sin(psi)*Dref(1);
            distance_WP2WP3(1) = sqrt((simOut.X(timeWP2)-XrWP2WP3(1))^2+(simOut.Y(timeWP2)-YrWP2WP3(1))^2);
            for i=2:Delta_t23
                Dref(i) = Dreal(i)*Lref/Lreal;
                XrWP2WP3(i) = XrWP2WP3(i-1)+cos(psi)*Dref(i);
                YrWP2WP3(i) = YrWP2WP3(i-1)+sin(psi)*Dref(i);
                distance_WP2WP3(i) = sqrt((simOut.X(timeWP2+i)-XrWP2WP3(i))^2+(simOut.Y(timeWP2+i)-YrWP2WP3(i))^2);
            end

            app.AverageDistanceWP2WP3EditField.Value = mean(distance_WP2WP3);                                  
            app.StandardDeviationDistanceWP2WP3EditField.Value = sqrt(sum(distance_WP2WP3.^2)/Delta_t23); 

            % Plot WP2-WP3
            data23x=[XrWP2WP3.', simOut.X(timeWP2:timeWP3)];
            data23y=[YrWP2WP3.', simOut.Y(timeWP2:timeWP3)];
            plot(app.UIAxes17,data23x,data23y);                                             
            hold(app.UIAxes17);
            WPx=[WP2(1) WP3(1)];
            WPy=[WP2(2) WP3(2)];
            plot(app.UIAxes17,WPx,WPy,'go');
            legend(app.UIAxes17,'Reference Trajectory','Effective Trajectory','Waypoints');
            str23 = {'WP2','WP3'};
            txt_x23 = [14.77 19.85];
            txt_y23 = [4.7 0.3];
            text(app.UIAxes17,txt_x23,txt_y23,str23)

            % Error computation WP3-WP4
            WP3 = [19.85 0.3];
            WP4 = [20.04 -9.7];
            timeWP3=floor(34.20/dt);
            timeWP4=floor(44.37/dt);
            Delta_t34 = timeWP4-timeWP3+1;
            XrWP3WP4 = 1:Delta_t34;
            YrWP3WP4 = 1:Delta_t34;
            distance_WP3WP4 = 1:Delta_t34;
            Lreal = 0;
            Dreal = 1:Delta_t34;
            Dref = 1:Delta_t34;
            DWP = WP4-WP3;
            Lref = sqrt(DWP(1).^2+DWP(2).^2);
            psi = atan(DWP(2)/DWP(1));

            for i=1:Delta_t34
                Dx = simOut.X(i+1)-simOut.X(i);
                Dy = simOut.Y(i+1)-simOut.Y(i);
                Dreal(i) = sqrt(Dx.^2+Dy.^2);
                Lreal = Lreal + Dreal(i);
            end

            Dref(1) = Dreal(1)*Lref/Lreal;
            XrWP3WP4(1) = WP3(1)+cos(psi)*Dref(1);
            YrWP3WP4(1) = WP3(2)+sin(psi)*Dref(1);
            distance_WP3WP4(1) = sqrt((simOut.X(timeWP3)-XrWP3WP4(1))^2+(simOut.Y(timeWP3)-YrWP3WP4(1))^2);
            for i=2:Delta_t34
                Dref(i) = Dreal(i)*Lref/Lreal;
                XrWP3WP4(i) = XrWP3WP4(i-1)+cos(psi)*Dref(i);
                YrWP3WP4(i) = YrWP3WP4(i-1)+sin(psi)*Dref(i);
                distance_WP3WP4(i) = sqrt((simOut.X(timeWP3+i)-XrWP3WP4(i))^2+(simOut.Y(timeWP3+i)-YrWP3WP4(i))^2);
            end

            app.AverageDistanceWP3WP4EditField.Value = mean(distance_WP3WP4);                                  
            app.StandardDeviationDistanceWP3WP4EditField.Value = sqrt(sum(distance_WP3WP4.^2)/Delta_t34);

            % Plot WP3-WP4
            data34x=[XrWP3WP4.', simOut.X(timeWP3:timeWP4)];
            data34y=[YrWP3WP4.', simOut.Y(timeWP3:timeWP4)];
            plot(app.UIAxes18,data34x,data34y);                                            
            hold(app.UIAxes18);
            WPx=[WP3(1) WP4(1)];
            WPy=[WP3(2) WP4(2)];
            plot(app.UIAxes18,WPx,WPy,'go');
            legend(app.UIAxes18,'Reference Trajectory','Effective Trajectory','Waypoints');
            str34 = {'WP3','WP4'};
            txt_x34 = [19.85 20.04];
            txt_y34 = [0.3 -9.7];
            text(app.UIAxes18,txt_x34,txt_y34,str34)

            % Error computation WP4-WP5
            WP4 = [20.04 -9.7];
            WP5 = [10.30 -10.13];
            timeWP4=floor(44.37/dt);
            timeWP5=floor(59.37/dt);
            Delta_t45 = timeWP5-timeWP4+1;
            XrWP4WP5 = 1:Delta_t45;
            YrWP4WP5 = 1:Delta_t45;
            distance_WP4WP5 = 1:Delta_t45;
            Lreal = 0;
            Dreal = 1:Delta_t45;
            Dref = 1:Delta_t45;
            DWP = WP5-WP4;
            Lref = sqrt(DWP(1).^2+DWP(2).^2);
            psi = atan(DWP(2)/DWP(1));

            for i=1:Delta_t45
                Dx = simOut.X(i+1)-simOut.X(i);
                Dy = simOut.Y(i+1)-simOut.Y(i);
                Dreal(i) = sqrt(Dx.^2+Dy.^2);
                Lreal = Lreal + Dreal(i);
            end

            Dref(1) = Dreal(1)*Lref/Lreal;
            XrWP4WP5(1) = WP4(1)-cos(psi)*Dref(1);
            YrWP4WP5(1) = WP4(2)-sin(psi)*Dref(1);
            distance_WP4WP5(1) = sqrt((simOut.X(timeWP4)-XrWP4WP5(1))^2+(simOut.Y(timeWP4)-YrWP4WP5(1))^2);
            for i=2:Delta_t45
                Dref(i) = Dreal(i)*Lref/Lreal;
                XrWP4WP5(i) = XrWP4WP5(i-1)-cos(psi)*Dref(i);
                YrWP4WP5(i) = YrWP4WP5(i-1)-sin(psi)*Dref(i);
                distance_WP4WP5(i) = sqrt((simOut.X(timeWP4+i)-XrWP4WP5(i))^2+(simOut.Y(timeWP4+i)-YrWP4WP5(i))^2);
            end

            app.AverageDistanceWP4WP5EditField.Value = mean(distance_WP4WP5);                                  
            app.StandardDeviationDistanceWP4WP5EditField.Value = sqrt(sum(distance_WP4WP5.^2)/Delta_t45); 

            % Plot WP4-WP5
            data45x=[XrWP4WP5.', simOut.X(timeWP4:timeWP5)];
            data45y=[YrWP4WP5.', simOut.Y(timeWP4:timeWP5)];
            plot(app.UIAxes19,data45x,data45y);                                            
            hold(app.UIAxes19);
            WPx=[WP4(1) WP5(1)];
            WPy=[WP4(2) WP5(2)];
            plot(app.UIAxes19,WPx,WPy,'go');
            legend(app.UIAxes19,'Reference Trajectory','Effective Trajectory','Waypoints');
            str45 = {'WP4','WP5'};
            txt_x45 = [20.04 10.30];
            txt_y45 = [-9.7 -10.13];
            text(app.UIAxes19,txt_x45,txt_y45,str45)

            % Error computation WP5-WP6
            WP5 = [10.30 -10.13];
            WP6 = [9.96 -0.30];
            timeWP5=floor(59.37/dt);
            timeWP6=floor(73.09/dt);
            Delta_t56 = timeWP6-timeWP5+1;
            XrWP5WP6 = 1:Delta_t56;
            YrWP5WP6 = 1:Delta_t56;
            distance_WP5WP6 = 1:Delta_t56;
            Lreal = 0;
            Dreal = 1:Delta_t56;
            Dref = 1:Delta_t56;
            DWP = WP6-WP5;
            Lref = sqrt(DWP(1).^2+DWP(2).^2);
            psi = atan(DWP(2)/DWP(1));

            for i=1:Delta_t56
                Dx = simOut.X(i+1)-simOut.X(i);
                Dy = simOut.Y(i+1)-simOut.Y(i);
                Dreal(i) = sqrt(Dx.^2+Dy.^2);
                Lreal = Lreal + Dreal(i);
            end

            Dref(1) = Dreal(1)*Lref/Lreal;
            XrWP5WP6(1) = WP5(1)-cos(psi)*Dref(1);
            YrWP5WP6(1) = WP5(2)-sin(psi)*Dref(1);
            distance_WP5WP6(1) = sqrt((simOut.X(timeWP5)-XrWP5WP6(1))^2+(simOut.Y(timeWP5)-YrWP5WP6(1))^2);
            for i=2:Delta_t56
                Dref(i) = Dreal(i)*Lref/Lreal;
                XrWP5WP6(i) = XrWP5WP6(i-1)-cos(psi)*Dref(i);
                YrWP5WP6(i) = YrWP5WP6(i-1)-sin(psi)*Dref(i);
                distance_WP5WP6(i) = sqrt((simOut.X(timeWP5+i)-XrWP5WP6(i))^2+(simOut.Y(timeWP5+i)-YrWP5WP6(i))^2);
            end

            app.AverageDistanceWP5WP6EditField.Value = mean(distance_WP5WP6);                                  
            app.StandardDeviationDistanceWP5WP6EditField.Value = sqrt(sum(distance_WP5WP6.^2)/Delta_t56); 

            % Plot WP5-WP6
            data56x=[XrWP5WP6.', simOut.X(timeWP5:timeWP6)];
            data56y=[YrWP5WP6.', simOut.Y(timeWP5:timeWP6)];
            plot(app.UIAxes20,data56x,data56y);                                             
            hold(app.UIAxes20);
            WPx=[WP5(1) WP6(1)];
            WPy=[WP5(2) WP6(2)];
            plot(app.UIAxes20,WPx,WPy,'go');
            legend(app.UIAxes20,'Reference Trajectory','Effective Trajectory','Waypoints');
            str56 = {'WP5','WP6'};
            txt_x56 = [10.30 9.96];
            txt_y56 = [-10.13 -0.30];
            text(app.UIAxes20,txt_x56,txt_y56,str56)

            % Error computation WP6-WP7
            WP6 = [9.96 -0.30];
            WP7 = [0.30 0.01];
            timeWP6=floor(73.09/dt);
            timeWP7=floor(88.39/dt);
            Delta_t67 = timeWP7-timeWP6+1;
            XrWP6WP7 = 1:Delta_t67;
            YrWP6WP7 = 1:Delta_t67;
            distance_WP6WP7 = 1:Delta_t67;
            Lreal = 0;
            Dreal = 1:Delta_t67;
            Dref = 1:Delta_t67;
            DWP = WP7-WP6;
            Lref = sqrt(DWP(1).^2+DWP(2).^2);
            psi = atan(DWP(2)/DWP(1));

            for i=1:Delta_t67
                Dx = simOut.X(i+1)-simOut.X(i);
                Dy = simOut.Y(i+1)-simOut.Y(i);
                Dreal(i) = sqrt(Dx.^2+Dy.^2);
                Lreal = Lreal + Dreal(i);
            end

            Dref(1) = Dreal(1)*Lref/Lreal;
            XrWP6WP7(1) = WP6(1)-cos(psi)*Dref(1);
            YrWP6WP7(1) = WP6(2)-sin(psi)*Dref(1);
            distance_WP6WP7(1) = sqrt((simOut.X(timeWP6)-XrWP6WP7(1))^2+(simOut.Y(timeWP6)-YrWP6WP7(1))^2);
            for i=2:Delta_t67
                Dref(i) = Dreal(i)*Lref/Lreal;
                XrWP6WP7(i) = XrWP6WP7(i-1)-cos(psi)*Dref(i);
                YrWP6WP7(i) = YrWP6WP7(i-1)-sin(psi)*Dref(i);
                distance_WP6WP7(i) = sqrt((simOut.X(timeWP6+i)-XrWP6WP7(i))^2+(simOut.Y(timeWP6+i)-YrWP6WP7(i))^2);
            end

            app.AverageDistanceWP6WP7EditField.Value = mean(distance_WP6WP7);                                  
            app.StandardDeviationDistanceWP6WP7EditField.Value = sqrt(sum(distance_WP6WP7.^2)/Delta_t67); 

            % Plot WP6-WP7
            data67x=[XrWP6WP7.', simOut.X(timeWP6:timeWP7)];
            data67y=[YrWP6WP7.', simOut.Y(timeWP6:timeWP7)];
            plot(app.UIAxes21,data67x,data67y);                                            
            hold(app.UIAxes21);
            WPx=[WP6(1) WP7(1)];
            WPy=[WP6(2) WP7(2)];
            plot(app.UIAxes21,WPx,WPy,'go');
            legend(app.UIAxes21,'Reference Trajectory','Effective Trajectory','Waypoints');
            str67 = {'WP6','WP7'};
            txt_x67 = [9.96 0.30];
            txt_y67 = [-0.30 0.01];
            text(app.UIAxes21,txt_x67,txt_y67,str67)

            % Plot fin kinematics
            cd 'Fin Kinematics'
            main
            cd ..


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UITable = uitable(app.MainTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.LoadParametersButton = uibutton(app.MainTab, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.ParametersLabel = uilabel(app.MainTab);
            app.ParametersLabel.Text = 'Parameters';
            app.RunSimulationButton = uibutton(app.MainTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.UIAxes_3 = uiaxes(app.PositionTab);
            title(app.UIAxes_3, 'Z ')
            xlabel(app.UIAxes_3, 'Time [s]')
            ylabel(app.UIAxes_3, 'Z [m]')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.PositionTab);
            title(app.UIAxes_2, 'Y')
            xlabel(app.UIAxes_2, 'Time [s]')
            ylabel(app.UIAxes_2, 'Y [m]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.PositionTab);
            title(app.UIAxes, 'X')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'X [m]')
            zlabel(app.UIAxes, 'Z')
            app.OrientationTab = uitab(app.TabGroup);
            app.OrientationTab.Title = 'Orientation';
            app.UIAxes_6 = uiaxes(app.OrientationTab);
            title(app.UIAxes_6, 'Yaw')
            xlabel(app.UIAxes_6, 'Time [s]')
            ylabel(app.UIAxes_6, 'Yaw [rad]')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_5 = uiaxes(app.OrientationTab);
            title(app.UIAxes_5, 'Pitch')
            xlabel(app.UIAxes_5, 'Time [s]')
            ylabel(app.UIAxes_5, 'Pitch [rad]')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_4 = uiaxes(app.OrientationTab);
            title(app.UIAxes_4, 'Roll')
            xlabel(app.UIAxes_4, 'Time [s]')
            ylabel(app.UIAxes_4, 'Roll [rad]')
            zlabel(app.UIAxes_4, 'Z')
            app.LinearVelocityTab = uitab(app.TabGroup);
            app.LinearVelocityTab.Title = 'Linear Velocity';
            app.UIAxes_9 = uiaxes(app.LinearVelocityTab);
            title(app.UIAxes_9, 'w')
            xlabel(app.UIAxes_9, 'Time [s]')
            ylabel(app.UIAxes_9, 'w [m/s]')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_8 = uiaxes(app.LinearVelocityTab);
            title(app.UIAxes_8, 'v')
            xlabel(app.UIAxes_8, 'Time [s]')
            ylabel(app.UIAxes_8, 'v [m/s]')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_7 = uiaxes(app.LinearVelocityTab);
            title(app.UIAxes_7, 'u')
            xlabel(app.UIAxes_7, 'Time [s]')
            ylabel(app.UIAxes_7, 'u [m/s]')
            zlabel(app.UIAxes_7, 'Z')
            app.AngularVelocityTab = uitab(app.TabGroup);
            app.AngularVelocityTab.Title = 'Angular Velocity';
            app.UIAxes_12 = uiaxes(app.AngularVelocityTab);
            title(app.UIAxes_12, 'r')
            xlabel(app.UIAxes_12, 'Time [s]')
            ylabel(app.UIAxes_12, 'r [rad/s]')
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_11 = uiaxes(app.AngularVelocityTab);
            title(app.UIAxes_11, 'q')
            xlabel(app.UIAxes_11, 'Time [s]')
            ylabel(app.UIAxes_11, 'q [rad/s]')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_10 = uiaxes(app.AngularVelocityTab);
            title(app.UIAxes_10, 'p')
            xlabel(app.UIAxes_10, 'Time [s]')
            ylabel(app.UIAxes_10, 'p [rad/s]')
            zlabel(app.UIAxes_10, 'Z')
            app.AllActuatorsTab = uitab(app.TabGroup);
            app.AllActuatorsTab.Title = 'All Actuators';
            app.UIAxes13 = uiaxes(app.AllActuatorsTab);
            title(app.UIAxes13, 'All Actuators')
            xlabel(app.UIAxes13, 'Time [s]')
            zlabel(app.UIAxes13, 'Z')
            app.TrajectoryTab = uitab(app.TabGroup);
            app.TrajectoryTab.Title = 'Trajectory';
            app.UIAxes14 = uiaxes(app.TrajectoryTab);
            title(app.UIAxes14, 'Effective trajectory with respect to reference trajectory')
            xlabel(app.UIAxes14, 'X [m]')
            ylabel(app.UIAxes14, 'Y [m]')
            zlabel(app.UIAxes14, 'Z')
            app.ErrorWP0WP1Tab = uitab(app.TabGroup);
            app.ErrorWP0WP1Tab.Title = 'Error WP0-WP1';
            app.UIAxes15 = uiaxes(app.ErrorWP0WP1Tab);
            title(app.UIAxes15, 'Effective trajectory w.r.t. reference trajectory')
            xlabel(app.UIAxes15, 'X [m]')
            ylabel(app.UIAxes15, 'Y [m]')
            zlabel(app.UIAxes15, 'Z')
            app.AverageDistanceWP0WP1EditFieldLabel = uilabel(app.ErrorWP0WP1Tab);
            app.AverageDistanceWP0WP1EditFieldLabel.Text = '  Average Distance WP0-WP1';
            app.AverageDistanceWP0WP1EditField = uieditfield(app.ErrorWP0WP1Tab, 'numeric');
            app.AverageDistanceWP0WP1EditField.Editable = 'off';
            app.StandardDeviationDistanceWP0WP1EditFieldLabel = uilabel(app.ErrorWP0WP1Tab);
            app.StandardDeviationDistanceWP0WP1EditFieldLabel.Text = 'Standard Deviation Distance WP0-WP1';
            app.StandardDeviationDistanceWP0WP1EditField = uieditfield(app.ErrorWP0WP1Tab, 'numeric');
            app.StandardDeviationDistanceWP0WP1EditField.Editable = 'off';
            app.ErrorWP1WP2Tab = uitab(app.TabGroup);
            app.ErrorWP1WP2Tab.Title = 'Error WP1-WP2';
            app.UIAxes16 = uiaxes(app.ErrorWP1WP2Tab);
            title(app.UIAxes16, 'Effective trajectory w.r.t. reference trajectory')
            xlabel(app.UIAxes16, 'X [m]')
            ylabel(app.UIAxes16, 'Y [m]')
            zlabel(app.UIAxes16, 'Z')
            app.AverageDistanceWP1WP2EditFieldLabel = uilabel(app.ErrorWP1WP2Tab);
            app.AverageDistanceWP1WP2EditFieldLabel.Text = '  Average Distance WP1-WP2';
            app.AverageDistanceWP1WP2EditField = uieditfield(app.ErrorWP1WP2Tab, 'numeric');
            app.AverageDistanceWP1WP2EditField.Editable = 'off';
            app.StandardDeviationDistanceWP1WP2EditFieldLabel = uilabel(app.ErrorWP1WP2Tab);
            app.StandardDeviationDistanceWP1WP2EditFieldLabel.Text = 'Standard Deviation Distance WP1-WP2';
            app.StandardDeviationDistanceWP1WP2EditField = uieditfield(app.ErrorWP1WP2Tab, 'numeric');
            app.StandardDeviationDistanceWP1WP2EditField.Editable = 'off';
            app.ErrorWP2WP3Tab = uitab(app.TabGroup);
            app.ErrorWP2WP3Tab.Title = 'Error WP2-WP3';
            app.UIAxes17 = uiaxes(app.ErrorWP2WP3Tab);
            title(app.UIAxes17, 'Effective trajectory w.r.t. reference trajectory')
            xlabel(app.UIAxes17, 'X [m]')
            ylabel(app.UIAxes17, 'Y [m]')
            zlabel(app.UIAxes17, 'Z')
            app.AverageDistanceWP2WP3EditFieldLabel = uilabel(app.ErrorWP2WP3Tab);
            app.AverageDistanceWP2WP3EditFieldLabel.Text = '  Average Distance WP2-WP3';
            app.AverageDistanceWP2WP3EditField = uieditfield(app.ErrorWP2WP3Tab, 'numeric');
            app.AverageDistanceWP2WP3EditField.Editable = 'off';
            app.StandardDeviationDistanceWP2WP3EditFieldLabel = uilabel(app.ErrorWP2WP3Tab);
            app.StandardDeviationDistanceWP2WP3EditFieldLabel.Text = 'Standard Deviation Distance WP2-WP3';
            app.StandardDeviationDistanceWP2WP3EditField = uieditfield(app.ErrorWP2WP3Tab, 'numeric');
            app.StandardDeviationDistanceWP2WP3EditField.Editable = 'off';
            app.ErrorWP3WP4Tab = uitab(app.TabGroup);
            app.ErrorWP3WP4Tab.Title = 'Error WP3-WP4';
            app.UIAxes18 = uiaxes(app.ErrorWP3WP4Tab);
            title(app.UIAxes18, 'Effective trajectory w.r.t. reference trajectory')
            xlabel(app.UIAxes18, 'X [m]')
            ylabel(app.UIAxes18, 'Y [m]')
            zlabel(app.UIAxes18, 'Z')
            app.AverageDistanceWP3WP4EditFieldLabel = uilabel(app.ErrorWP3WP4Tab);
            app.AverageDistanceWP3WP4EditFieldLabel.Text = '  Average Distance WP3-WP4';
            app.AverageDistanceWP3WP4EditField = uieditfield(app.ErrorWP3WP4Tab, 'numeric');
            app.AverageDistanceWP3WP4EditField.Editable = 'off';
            app.StandardDeviationDistanceWP3WP4EditFieldLabel = uilabel(app.ErrorWP3WP4Tab);
            app.StandardDeviationDistanceWP3WP4EditFieldLabel.Text = 'Standard Deviation Distance WP3-WP4';
            app.StandardDeviationDistanceWP3WP4EditField = uieditfield(app.ErrorWP3WP4Tab, 'numeric');
            app.StandardDeviationDistanceWP3WP4EditField.Editable = 'off';
            app.ErrorWP4WP5Tab = uitab(app.TabGroup);
            app.ErrorWP4WP5Tab.Title = 'Error WP4-WP5';
            app.UIAxes19 = uiaxes(app.ErrorWP4WP5Tab);
            title(app.UIAxes19, 'Effective trajectory w.r.t. reference trajectory')
            xlabel(app.UIAxes19, 'X [m]')
            ylabel(app.UIAxes19, 'Y [m]')
            zlabel(app.UIAxes19, 'Z')
            app.AverageDistanceWP4WP5EditFieldLabel = uilabel(app.ErrorWP4WP5Tab);
            app.AverageDistanceWP4WP5EditFieldLabel.Text = '  Average Distance WP4-WP5';
            app.AverageDistanceWP4WP5EditField = uieditfield(app.ErrorWP4WP5Tab, 'numeric');
            app.AverageDistanceWP4WP5EditField.Editable = 'off';
            app.StandardDeviationDistanceWP4WP5EditFieldLabel = uilabel(app.ErrorWP4WP5Tab);
            app.StandardDeviationDistanceWP4WP5EditFieldLabel.Text = 'Standard Deviation Distance WP4-WP5';
            app.StandardDeviationDistanceWP4WP5EditField = uieditfield(app.ErrorWP4WP5Tab, 'numeric');
            app.StandardDeviationDistanceWP4WP5EditField.Editable = 'off';
            app.ErrorWP5WP6Tab = uitab(app.TabGroup);
            app.ErrorWP5WP6Tab.Title = 'Error WP5-WP6';
            app.UIAxes20 = uiaxes(app.ErrorWP5WP6Tab);
            title(app.UIAxes20, 'Effective trajectory w.r.t. reference trajectory')
            xlabel(app.UIAxes20, 'X [m]')
            ylabel(app.UIAxes20, 'Y [m]')
            zlabel(app.UIAxes20, 'Z')
            app.AverageDistanceWP5WP6EditFieldLabel = uilabel(app.ErrorWP5WP6Tab);
            app.AverageDistanceWP5WP6EditFieldLabel.Text = '  Average Distance WP5-WP6';
            app.AverageDistanceWP5WP6EditField = uieditfield(app.ErrorWP5WP6Tab, 'numeric');
            app.AverageDistanceWP5WP6EditField.Editable = 'off';
            app.StandardDeviationDistanceWP5WP6EditFieldLabel = uilabel(app.ErrorWP5WP6Tab);
            app.StandardDeviationDistanceWP5WP6EditFieldLabel.Text = 'Standard Deviation Distance WP5-WP6';
            app.StandardDeviationDistanceWP5WP6EditField = uieditfield(app.ErrorWP5WP6Tab, 'numeric');
            app.StandardDeviationDistanceWP5WP6EditField.Editable = 'off';
            app.ErrorWP6WP7Tab = uitab(app.TabGroup);
            app.ErrorWP6WP7Tab.Title = 'Error WP6-WP7';
            app.UIAxes21 = uiaxes(app.ErrorWP6WP7Tab);
            title(app.UIAxes21, 'Effective trajectory w.r.t. reference trajectory')
            xlabel(app.UIAxes21, 'X [m]')
            ylabel(app.UIAxes21, 'Y [m]')
            zlabel(app.UIAxes21, 'Z')
            app.AverageDistanceWP6WP7EditFieldLabel = uilabel(app.ErrorWP6WP7Tab);
            app.AverageDistanceWP6WP7EditFieldLabel.Text = '  Average Distance WP6-WP7';
            app.AverageDistanceWP6WP7EditField = uieditfield(app.ErrorWP6WP7Tab, 'numeric');
            app.AverageDistanceWP6WP7EditField.Editable = 'off';
            app.StandardDeviationDistanceWP6WP7EditFieldLabel = uilabel(app.ErrorWP6WP7Tab);
            app.StandardDeviationDistanceWP6WP7EditFieldLabel.Text = 'Standard Deviation Distance WP6-WP7';
            app.StandardDeviationDistanceWP6WP7EditField = uieditfield(app.ErrorWP6WP7Tab, 'numeric');
            app.StandardDeviationDistanceWP6WP7EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end